classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ModeMenu                 matlab.ui.container.Menu
        CalibrationMenu          matlab.ui.container.Menu
        sensorsMenu              matlab.ui.container.Menu
        pumpsMenu                matlab.ui.container.Menu
        valvesMenu               matlab.ui.container.Menu
        ControllerMenu           matlab.ui.container.Menu
        Menu_4                   matlab.ui.container.Menu
        Menu2_3                  matlab.ui.container.Menu
        Menu3_2                  matlab.ui.container.Menu
        Menu4                    matlab.ui.container.Menu
        Menu5                    matlab.ui.container.Menu
        Menu6                    matlab.ui.container.Menu
        Menu7                    matlab.ui.container.Menu
        StopButton               matlab.ui.control.Button
        SensorPanel              matlab.ui.container.Panel
        confirmButton_27         matlab.ui.control.Button
        confirmButton_26         matlab.ui.control.Button
        confirmButton_25         matlab.ui.control.Button
        confirmButton_24         matlab.ui.control.Button
        confirmButton_23         matlab.ui.control.Button
        confirmButton_22         matlab.ui.control.Button
        confirmButton_21         matlab.ui.control.Button
        confirmButton_20         matlab.ui.control.Button
        confirmButton_19         matlab.ui.control.Button
        confirmButton_18         matlab.ui.control.Button
        confirmButton_17         matlab.ui.control.Button
        confirmButton_16         matlab.ui.control.Button
        confirmButton_15         matlab.ui.control.Button
        confirmButton_14         matlab.ui.control.Button
        confirmButton_13         matlab.ui.control.Button
        confirmButton_12         matlab.ui.control.Button
        confirmButton_11         matlab.ui.control.Button
        confirmButton_10         matlab.ui.control.Button
        confirmButton_9          matlab.ui.control.Button
        confirmButton_8          matlab.ui.control.Button
        confirmButton_7          matlab.ui.control.Button
        confirmButton_6          matlab.ui.control.Button
        confirmButton_5          matlab.ui.control.Button
        confirmButton_4          matlab.ui.control.Button
        confirmButton_3          matlab.ui.control.Button
        confirmButton_2          matlab.ui.control.Button
        confirmButton_1          matlab.ui.control.Button
        Tank3Label_2             matlab.ui.control.Label
        Tank2Label_2             matlab.ui.control.Label
        Tank1Label_2             matlab.ui.control.Label
        Label_18                 matlab.ui.control.Label
        Label_17                 matlab.ui.control.Label
        Label_16                 matlab.ui.control.Label
        Label_15                 matlab.ui.control.Label
        Label_14                 matlab.ui.control.Label
        Label_13                 matlab.ui.control.Label
        Label_12                 matlab.ui.control.Label
        Label_11                 matlab.ui.control.Label
        Label_10                 matlab.ui.control.Label
        SaveVisualizationButton  matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        ValvePanel               matlab.ui.container.Panel
        StopButton_3             matlab.ui.control.StateButton
        StartButton_3            matlab.ui.control.StateButton
        Label_19                 matlab.ui.control.Label
        ValveVoltageAxes         matlab.ui.control.UIAxes
        PumpPanel                matlab.ui.container.Panel
        SetLabel                 matlab.ui.control.Label
        StopButton_2             matlab.ui.control.StateButton
        StartButton_2            matlab.ui.control.StateButton
        PumpVoltageAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
            PumpPlotTimer
            PumpLine1
            PumpLine2
            PumpStartTime
            ValvePlotTimer
            ValveLine1
            ValveLine2
            ValveLine3
            ValveStartTime
    end
    
    methods (Access = private)
        
        function recordPoint(app, tank, level, button)

            mdl = "Copy_2_of_calibration_sensors";
        
            blockName = sprintf("confirmlevel%d_%d", level, tank);
            blockPath = mdl + "/" + blockName;
        
            simStatus = get_param(mdl, "SimulationStatus");
        
            if ~strcmp(simStatus, "running")
                uialert(app.UIFigure, ...
                    "Please start Sensor Calibration first.", ...
                    "Simulation not running");
                return
            end
        
            set_param(blockPath, "Value", "1");
            pause(0.2);
            set_param(blockPath, "Value", "0");
        
            button.Text = "Recorded";
            button.Enable = "off";
        
        end

        function updatePumpPlot(app)
        
            mdl = "Copy_of_calibration_pumps";
        
            if ~bdIsLoaded(mdl)
                return
            end
        
            if ~strcmp(get_param(mdl, "SimulationStatus"), "running")
                return
            end
        
            try
        
                blk1 = mdl + "/GUI_ADC1";
                blk3 = mdl + "/GUI_ADC3";
        
                rto1 = get_param(blk1, "RuntimeObject");
                rto3 = get_param(blk3, "RuntimeObject");
        
                adc1 = double(rto1.OutputPort(1).Data);
                adc3 = double(rto3.OutputPort(1).Data);
        
                fprintf("adc1 = %.3f V, adc3 = %.3f V\n", ...
                    adc1, adc3);
        
                t = toc(app.PumpStartTime);
        
                addpoints(app.PumpLine1, t, adc1);
                addpoints(app.PumpLine2, t, adc3);
        
                window = 30;
        
                if t <= window
                    app.PumpVoltageAxes.XLim = [0 window];
                else
                    app.PumpVoltageAxes.XLim = [t-window t];
                end
        
                drawnow limitrate
        
            catch ME
        
                disp("updatePumpPlot ERROR:");
                disp(ME.message);
        
            end
        
        end

        function updateValvePlot(app)

            mdl = "Copy_of_calibration_valves";

            if ~bdIsLoaded(mdl)
                return
            end

            if ~strcmp(get_param(mdl, "SimulationStatus"), "running")
                return
            end

            try

                blk1 = mdl + "/GUI_ADC1";
                blk2 = mdl + "/GUI_ADC2";
                blk3 = mdl + "/GUI_ADC3";

                rto1 = get_param(blk1, "RuntimeObject");
                rto2 = get_param(blk2, "RuntimeObject");
                rto3 = get_param(blk3, "RuntimeObject");

                adc1 = double(rto1.OutputPort(1).Data);
                adc2 = double(rto2.OutputPort(1).Data);
                adc3 = double(rto3.OutputPort(1).Data);

                t = toc(app.ValveStartTime);

                addpoints(app.ValveLine1, t, adc1);
                addpoints(app.ValveLine2, t, adc3);
                addpoints(app.ValveLine3, t, adc2);

                window = 30;

                if t <= window
                    app.ValveVoltageAxes.XLim = [0 window];
                else
                    app.ValveVoltageAxes.XLim = [t-window t];
                end

                drawnow limitrate

            catch ME

                disp("updateValvePlot ERROR:");
                disp(ME.message);

            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: sensorsMenu
        function sensorsMenuSelected(app, event)
            app.ValvePanel.Visible = "off";
            app.PumpPanel.Visible   = "off";
            app.SensorPanel.Visible  = "on";
        end

        % Menu selected function: pumpsMenu
        function pumpsMenuSelected(app, event)
            app.ValvePanel.Visible = "off";
            app.PumpPanel.Visible   = "on";
            app.SensorPanel.Visible  = "off";
        end

        % Menu selected function: valvesMenu
        function valvesMenuSelected(app, event)
            app.ValvePanel.Visible = "on";
            app.PumpPanel.Visible   = "off";
            app.SensorPanel.Visible  = "off";
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            mdl = "Copy_2_of_calibration_sensors";

            try
                load_system(mdl);

                set_param(mdl, "StopTime", "inf");

                set_param(mdl, ...
                    "SimulationCommand", ...
                    "start");

                pause(0.3);

                status = get_param(mdl, "SimulationStatus");

                if strcmp(status, "running")

                    app.StartButton.Text = "Running";
                    app.StartButton.Enable = "off";

                else

                    app.StartButton.Text = "Start";

                    uialert(app.UIFigure, ...
                        "Model status: " + string(status), ...
                        "Simulation Error");
                end

            catch ME

                app.StartButton.Text = "Start";

                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Start Error");

            end
        end

        % Button pushed function: confirmButton_1
        function confirmButton_1Pushed(app, event)
            app.recordPoint(1, 1, app.confirmButton_1);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            mdl = "Copy_2_of_calibration_sensors";
        
            try
        
                status = get_param(mdl, "SimulationStatus");
        
                if strcmp(status, "running")
                    set_param(mdl, ...
                        "SimulationCommand", ...
                        "stop");
                end
        
                app.StartButton.Text = "Start";
                app.StartButton.Enable = "on";
        
            catch ME
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Sensor Stop Error");
        
            end
        end

        % Button pushed function: confirmButton_2
        function confirmButton_2Pushed(app, event)
            app.recordPoint(2, 1, app.confirmButton_2);
        end

        % Button pushed function: confirmButton_3
        function confirmButton_3Pushed(app, event)
            app.recordPoint(3, 1, app.confirmButton_3);
        end

        % Button pushed function: confirmButton_4
        function confirmButton_4Pushed(app, event)
            app.recordPoint(1, 2, app.confirmButton_4);
        end

        % Button pushed function: confirmButton_5
        function confirmButton_5Pushed(app, event)
            app.recordPoint(2, 2, app.confirmButton_5);
        end

        % Button pushed function: confirmButton_6
        function confirmButton_6Pushed(app, event)
            app.recordPoint(3, 2, app.confirmButton_6);
        end

        % Button pushed function: confirmButton_7
        function confirmButton_7Pushed(app, event)
            app.recordPoint(1, 3, app.confirmButton_7);
        end

        % Button pushed function: confirmButton_8
        function confirmButton_8Pushed(app, event)
            app.recordPoint(2, 3, app.confirmButton_8);
        end

        % Button pushed function: confirmButton_9
        function confirmButton_9Pushed(app, event)
            app.recordPoint(3, 3, app.confirmButton_9);
        end

        % Button pushed function: confirmButton_10
        function confirmButton_10Pushed(app, event)
            app.recordPoint(1, 4, app.confirmButton_10);
        end

        % Button pushed function: confirmButton_11
        function confirmButton_11Pushed(app, event)
            app.recordPoint(2, 4, app.confirmButton_11);
        end

        % Button pushed function: confirmButton_12
        function confirmButton_12Pushed(app, event)
            app.recordPoint(3, 4, app.confirmButton_12);
        end

        % Button pushed function: confirmButton_13
        function confirmButton_13Pushed(app, event)
            app.recordPoint(1, 5, app.confirmButton_13);
        end

        % Button pushed function: confirmButton_14
        function confirmButton_14Pushed(app, event)
            app.recordPoint(2, 5, app.confirmButton_14);
        end

        % Button pushed function: confirmButton_15
        function confirmButton_15Pushed(app, event)
            app.recordPoint(3, 5, app.confirmButton_15);
        end

        % Button pushed function: confirmButton_16
        function confirmButton_16Pushed(app, event)
            app.recordPoint(1, 6, app.confirmButton_16);
        end

        % Button pushed function: confirmButton_17
        function confirmButton_17Pushed(app, event)
            app.recordPoint(2, 6, app.confirmButton_17);
        end

        % Button pushed function: confirmButton_18
        function confirmButton_18Pushed(app, event)
            app.recordPoint(3, 6, app.confirmButton_18);
        end

        % Button pushed function: confirmButton_19
        function confirmButton_19Pushed(app, event)
            app.recordPoint(1, 7, app.confirmButton_19);
        end

        % Button pushed function: confirmButton_20
        function confirmButton_20Pushed(app, event)
            app.recordPoint(2, 7, app.confirmButton_20);
        end

        % Button pushed function: confirmButton_21
        function confirmButton_21Pushed(app, event)
            app.recordPoint(3, 7, app.confirmButton_21);
        end

        % Button pushed function: confirmButton_22
        function confirmButton_22Pushed(app, event)
            app.recordPoint(1, 8, app.confirmButton_22);
        end

        % Button pushed function: confirmButton_23
        function confirmButton_23Pushed(app, event)
            app.recordPoint(2, 8, app.confirmButton_23);
        end

        % Button pushed function: confirmButton_24
        function confirmButton_24Pushed(app, event)
            app.recordPoint(3, 8, app.confirmButton_24);
        end

        % Button pushed function: confirmButton_25
        function confirmButton_25Pushed(app, event)
            app.recordPoint(1, 9, app.confirmButton_25);
        end

        % Button pushed function: confirmButton_26
        function confirmButton_26Pushed(app, event)
            app.recordPoint(2, 9, app.confirmButton_26);
        end

        % Button pushed function: confirmButton_27
        function confirmButton_27Pushed(app, event)
            app.recordPoint(3, 9, app.confirmButton_27);
        end

        % Value changed function: StartButton_2
        function StartButton_2ValueChanged(app, event)
            mdl = "Copy_of_calibration_pumps";
        
            try
        
                load_system(mdl);
        
                set_param(mdl, "BlockReduction", "off");
        
                set_param(mdl, "StopTime", "inf");
        
                set_param(mdl, ...
                    "SimulationCommand", ...
                    "start");
        
                pause(0.3);
        
                status = get_param(mdl, "SimulationStatus");
        
                if strcmp(status, "running")
        
                    app.StartButton_2.Text = "Running";
                    app.StartButton_2.Enable = "off";
        
                    cla(app.PumpVoltageAxes);
        
                    app.PumpLine1 = animatedline( ...
                        app.PumpVoltageAxes, ...
                        "LineWidth", 1.8, ...
                        "Color", [0 0.4470 0.7410], ...
                        "DisplayName", "Tank 1");
                    
                    app.PumpLine2 = animatedline( ...
                        app.PumpVoltageAxes, ...
                        "LineWidth", 1.8, ...
                        "Color", [0.8500 0.3250 0.0980], ...
                        "DisplayName", "Tank 2");
        
                    xlabel(app.PumpVoltageAxes, "Time (s)");
                    ylabel(app.PumpVoltageAxes, "Voltage (V)");
                    title(app.PumpVoltageAxes, ...
                        "Real-Time Sensor Voltage");
        
                    ylim(app.PumpVoltageAxes, [0 3.3]);
                    xlim(app.PumpVoltageAxes, [0 30]);
        
                    grid(app.PumpVoltageAxes, "on");
        
                    legend(app.PumpVoltageAxes, ...
                        "show", ...
                        "Location", "best");
        
                    app.PumpStartTime = tic;
        
                    if ~isempty(app.PumpPlotTimer) && ...
                            isvalid(app.PumpPlotTimer)
        
                        stop(app.PumpPlotTimer);
                        delete(app.PumpPlotTimer);
                    end
        
                    app.PumpPlotTimer = timer( ...
                        "ExecutionMode", "fixedSpacing", ...
                        "Period", 0.1, ...
                        "TimerFcn", @(~,~) app.updatePumpPlot());
        
                    start(app.PumpPlotTimer);
        
                else
        
                    app.StartButton_2.Text = "Start";
                    app.StartButton_2.Enable = "on";
        
                    uialert(app.UIFigure, ...
                        "Model status: " + string(status), ...
                        "Pump Calibration Error");
        
                end
        
            catch ME
        
                app.StartButton_2.Text = "Start";
                app.StartButton_2.Enable = "on";
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Pump Start Error");
        
            end
        end

        % Value changed function: StopButton_2
        function StopButton_2ValueChanged(app, event)
            mdl = "Copy_of_calibration_pumps";
        
            try
        
                if ~isempty(app.PumpPlotTimer) && ...
                        isvalid(app.PumpPlotTimer)
        
                    stop(app.PumpPlotTimer);
                    delete(app.PumpPlotTimer);
        
                    app.PumpPlotTimer = [];
        
                end
        
                if bdIsLoaded(mdl)
        
                    status = get_param(mdl, ...
                        "SimulationStatus");
        
                    if ~strcmp(status, "stopped")
        
                        set_param(mdl, ...
                            "SimulationCommand", ...
                            "stop");
        
                    end
        
                end
        
                app.StartButton_2.Text = "Start";
                app.StartButton_2.Enable = "on";
        
            catch ME
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Pump Stop Error");
        
            end

        end

        % Value changed function: StartButton_3
        function StartButton_3ValueChanged(app, event)
            mdl = "Copy_of_calibration_valves";
        
            try
        
                load_system(mdl);
        
                set_param(mdl, "BlockReduction", "off");
        
                set_param(mdl, "StopTime", "inf");
        
                set_param(mdl, ...
                    "SimulationCommand", ...
                    "start");
        
                pause(0.3);
        
                status = get_param(mdl, "SimulationStatus");
        
                if strcmp(status, "running")
        
                    app.StartButton_3.Text = "Running";
                    app.StartButton_3.Enable = "off";
        
                    cla(app.ValveVoltageAxes);
        
                    app.ValveLine1 = animatedline( ...
                        app.ValveVoltageAxes, ...
                        "LineWidth", 1.8, ...
                        "Color", [0 0.4470 0.7410], ...
                        "DisplayName", "Tank 1");
        
                    app.ValveLine2 = animatedline( ...
                        app.ValveVoltageAxes, ...
                        "LineWidth", 1.8, ...
                        "Color", [0.8500 0.3250 0.0980], ...
                        "DisplayName", "Tank 2");
        
                    app.ValveLine3 = animatedline( ...
                        app.ValveVoltageAxes, ...
                        "LineWidth", 1.8, ...
                        "Color", [0.4660 0.6740 0.1880], ...
                        "DisplayName", "Tank 3");
        
                    xlabel(app.ValveVoltageAxes, "Time (s)");
                    ylabel(app.ValveVoltageAxes, "Voltage (V)");
        
                    title(app.ValveVoltageAxes, ...
                        "Real-Time Sensor Voltage");
        
                    ylim(app.ValveVoltageAxes, [0 3.3]);
                    xlim(app.ValveVoltageAxes, [0 30]);
        
                    grid(app.ValveVoltageAxes, "on");
        
                    legend(app.ValveVoltageAxes, ...
                        "show", ...
                        "Location", "best");
        
                    app.ValveStartTime = tic;
        
                    if ~isempty(app.ValvePlotTimer) && ...
                            isvalid(app.ValvePlotTimer)
        
                        stop(app.ValvePlotTimer);
                        delete(app.ValvePlotTimer);
        
                    end
        
                    app.ValvePlotTimer = timer( ...
                        "ExecutionMode", "fixedSpacing", ...
                        "Period", 0.1, ...
                        "TimerFcn", @(~,~) app.updateValvePlot());
        
                    start(app.ValvePlotTimer);
        
                else
        
                    app.StartButton_3.Text = "Start";
                    app.StartButton_3.Enable = "on";
        
                    uialert(app.UIFigure, ...
                        "Model status: " + string(status), ...
                        "Valve Calibration Error");
        
                end
        
            catch ME
        
                app.StartButton_3.Text = "Start";
                app.StartButton_3.Enable = "on";
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Valve Start Error");
        
            end
        end

        % Value changed function: StopButton_3
        function StopButton_3ValueChanged(app, event)
            mdl = "Copy_of_calibration_valves";
        
            try
        
                if ~isempty(app.ValvePlotTimer) && ...
                        isvalid(app.ValvePlotTimer)
        
                    stop(app.ValvePlotTimer);
                    delete(app.ValvePlotTimer);
        
                    app.ValvePlotTimer = [];
        
                end
        
                if bdIsLoaded(mdl)
        
                    status = get_param(mdl, "SimulationStatus");
        
                    if ~strcmp(status, "stopped")
        
                        set_param(mdl, ...
                            "SimulationCommand", ...
                            "stop");
        
                    end
        
                end
        
                app.StartButton_3.Text = "Start";
                app.StartButton_3.Enable = "on";
        
            catch ME
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Valve Stop Error");
        
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ModeMenu = uimenu(app.UIFigure);
            app.ModeMenu.Text = 'Mode';
            app.CalibrationMenu = uimenu(app.ModeMenu);
            app.CalibrationMenu.Text = 'Calibration';
            app.sensorsMenu = uimenu(app.CalibrationMenu);
            app.sensorsMenu.MenuSelectedFcn = createCallbackFcn(app, @sensorsMenuSelected, true);
            app.sensorsMenu.Text = 'sensors';
            app.pumpsMenu = uimenu(app.CalibrationMenu);
            app.pumpsMenu.MenuSelectedFcn = createCallbackFcn(app, @pumpsMenuSelected, true);
            app.pumpsMenu.Text = 'pumps';
            app.valvesMenu = uimenu(app.CalibrationMenu);
            app.valvesMenu.MenuSelectedFcn = createCallbackFcn(app, @valvesMenuSelected, true);
            app.valvesMenu.Text = 'valves';
            app.ControllerMenu = uimenu(app.ModeMenu);
            app.ControllerMenu.Text = 'Controller';
            app.Menu_4 = uimenu(app.ControllerMenu);
            app.Menu_4.Text = 'Menu';
            app.Menu2_3 = uimenu(app.ControllerMenu);
            app.Menu2_3.Text = 'Menu2';
            app.Menu3_2 = uimenu(app.ControllerMenu);
            app.Menu3_2.Text = 'Menu3';
            app.Menu4 = uimenu(app.ControllerMenu);
            app.Menu4.Text = 'Menu4';
            app.Menu5 = uimenu(app.ControllerMenu);
            app.Menu5.Text = 'Menu5';
            app.Menu6 = uimenu(app.ControllerMenu);
            app.Menu6.Text = 'Menu6';
            app.Menu7 = uimenu(app.ControllerMenu);
            app.Menu7.Text = 'Menu7';
            app.PumpPanel = uipanel(app.UIFigure);
            app.PumpVoltageAxes = uiaxes(app.PumpPanel);
            xlabel(app.PumpVoltageAxes, 'Time ')
            ylabel(app.PumpVoltageAxes, 'Voltage ')
            zlabel(app.PumpVoltageAxes, 'Z')
            app.PumpVoltageAxes.XLim = [0 30];
            app.PumpVoltageAxes.YLim = [0 3.3];
            app.PumpVoltageAxes.XTick = [];
            app.PumpVoltageAxes.XTickLabel = '';
            app.PumpVoltageAxes.YTick = [0 0.5 1 1.5 2 2.5 3];
            app.PumpVoltageAxes.YTickLabel = {'0'; '0.5'; '1'; '1.5'; '2'; '2.5'; '3'};
            app.StartButton_2 = uibutton(app.PumpPanel, 'state');
            app.StartButton_2.ValueChangedFcn = createCallbackFcn(app, @StartButton_2ValueChanged, true);
            app.StartButton_2.Text = 'Start';
            app.StopButton_2 = uibutton(app.PumpPanel, 'state');
            app.StopButton_2.ValueChangedFcn = createCallbackFcn(app, @StopButton_2ValueChanged, true);
            app.StopButton_2.Text = 'Stop';
            app.SetLabel = uilabel(app.PumpPanel);
            app.SetLabel.Text = 'Set the water level to 10 10 10 and press Start.';
            app.ValvePanel = uipanel(app.UIFigure);
            app.ValveVoltageAxes = uiaxes(app.ValvePanel);
            xlabel(app.ValveVoltageAxes, 'Time')
            ylabel(app.ValveVoltageAxes, 'Voltage')
            zlabel(app.ValveVoltageAxes, 'Z')
            app.ValveVoltageAxes.XLim = [0 30];
            app.ValveVoltageAxes.YLim = [0 3.3];
            app.ValveVoltageAxes.XTick = [];
            app.ValveVoltageAxes.YTick = [0 0.5 1 1.5 2 2.5 3 3.3];
            app.ValveVoltageAxes.YTickLabel = {'0'; '0.5'; '1'; '1.5'; '2'; '2.5'; '3'; '3.3'};
            app.Label_19 = uilabel(app.ValvePanel);
            app.Label_19.Text = 'Set the water level to 40,30,20 and press start';
            app.StartButton_3 = uibutton(app.ValvePanel, 'state');
            app.StartButton_3.ValueChangedFcn = createCallbackFcn(app, @StartButton_3ValueChanged, true);
            app.StartButton_3.Text = 'Start';
            app.StopButton_3 = uibutton(app.ValvePanel, 'state');
            app.StopButton_3.ValueChangedFcn = createCallbackFcn(app, @StopButton_3ValueChanged, true);
            app.StopButton_3.Text = 'Stop';
            app.SensorPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.SensorPanel);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Voltage')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 30];
            app.UIAxes.YLim = [0 3.3];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [0 0.5 1 1.5 2 2.5 3 3.3];
            app.UIAxes.YTickLabel = {'0'; '0.5'; '1'; '1.5'; '2'; '2.5'; '3'; '3.3'};
            app.StartButton = uibutton(app.SensorPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveVisualizationButton = uibutton(app.SensorPanel, 'push');
            app.SaveVisualizationButton.Text = 'Save & Visualization';
            app.Label_10 = uilabel(app.SensorPanel);
            app.Label_10.Text = '10';
            app.Label_11 = uilabel(app.SensorPanel);
            app.Label_11.Text = '15';
            app.Label_12 = uilabel(app.SensorPanel);
            app.Label_12.Text = '20';
            app.Label_13 = uilabel(app.SensorPanel);
            app.Label_13.Text = '25';
            app.Label_14 = uilabel(app.SensorPanel);
            app.Label_14.Text = '30';
            app.Label_15 = uilabel(app.SensorPanel);
            app.Label_15.Text = '35';
            app.Label_16 = uilabel(app.SensorPanel);
            app.Label_16.Text = '40';
            app.Label_17 = uilabel(app.SensorPanel);
            app.Label_17.Text = '45';
            app.Label_18 = uilabel(app.SensorPanel);
            app.Label_18.Text = '50';
            app.Tank1Label_2 = uilabel(app.SensorPanel);
            app.Tank1Label_2.Text = 'Tank1';
            app.Tank2Label_2 = uilabel(app.SensorPanel);
            app.Tank2Label_2.Text = 'Tank2';
            app.Tank3Label_2 = uilabel(app.SensorPanel);
            app.Tank3Label_2.Text = 'Tank3';
            app.confirmButton_1 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_1.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_1Pushed, true);
            app.confirmButton_1.Text = 'confirm';
            app.confirmButton_2 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_2.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_2Pushed, true);
            app.confirmButton_2.Text = 'confirm';
            app.confirmButton_3 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_3.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_3Pushed, true);
            app.confirmButton_3.Text = 'confirm';
            app.confirmButton_4 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_4.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_4Pushed, true);
            app.confirmButton_4.Text = 'confirm';
            app.confirmButton_5 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_5.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_5Pushed, true);
            app.confirmButton_5.Text = 'confirm';
            app.confirmButton_6 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_6.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_6Pushed, true);
            app.confirmButton_6.Text = 'confirm';
            app.confirmButton_7 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_7.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_7Pushed, true);
            app.confirmButton_7.Text = 'confirm';
            app.confirmButton_8 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_8.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_8Pushed, true);
            app.confirmButton_8.Text = 'confirm';
            app.confirmButton_9 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_9.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_9Pushed, true);
            app.confirmButton_9.Text = 'confirm';
            app.confirmButton_10 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_10.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_10Pushed, true);
            app.confirmButton_10.Text = 'confirm';
            app.confirmButton_11 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_11.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_11Pushed, true);
            app.confirmButton_11.Text = 'confirm';
            app.confirmButton_12 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_12.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_12Pushed, true);
            app.confirmButton_12.Text = 'confirm';
            app.confirmButton_13 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_13.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_13Pushed, true);
            app.confirmButton_13.Text = 'confirm';
            app.confirmButton_14 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_14.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_14Pushed, true);
            app.confirmButton_14.Text = 'confirm';
            app.confirmButton_15 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_15.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_15Pushed, true);
            app.confirmButton_15.Text = 'confirm';
            app.confirmButton_16 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_16.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_16Pushed, true);
            app.confirmButton_16.Text = 'confirm';
            app.confirmButton_17 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_17.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_17Pushed, true);
            app.confirmButton_17.Text = 'confirm';
            app.confirmButton_18 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_18.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_18Pushed, true);
            app.confirmButton_18.Text = 'confirm';
            app.confirmButton_19 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_19.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_19Pushed, true);
            app.confirmButton_19.Text = 'confirm';
            app.confirmButton_20 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_20.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_20Pushed, true);
            app.confirmButton_20.Text = 'confirm';
            app.confirmButton_21 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_21.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_21Pushed, true);
            app.confirmButton_21.Text = 'confirm';
            app.confirmButton_22 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_22.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_22Pushed, true);
            app.confirmButton_22.Text = 'confirm';
            app.confirmButton_23 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_23.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_23Pushed, true);
            app.confirmButton_23.Text = 'confirm';
            app.confirmButton_24 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_24.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_24Pushed, true);
            app.confirmButton_24.Text = 'confirm';
            app.confirmButton_25 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_25.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_25Pushed, true);
            app.confirmButton_25.Text = 'confirm';
            app.confirmButton_26 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_26.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_26Pushed, true);
            app.confirmButton_26.Text = 'confirm';
            app.confirmButton_27 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_27.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_27Pushed, true);
            app.confirmButton_27.Text = 'confirm';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end